classdef app8 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        holdonSwitch                    matlab.ui.control.Switch
        holdonSwitchLabel               matlab.ui.control.Label
        numberofswitchingfrequencydatepointSpinner  matlab.ui.control.Spinner
        numberofswitchingfrequencydatepointSpinnerLabel  matlab.ui.control.Label
        numberofphasevoltagedatepointSpinner  matlab.ui.control.Spinner
        numberofphasevoltagedatepointSpinnerLabel  matlab.ui.control.Label
        switchingfrequencyendpointHzEditField  matlab.ui.control.NumericEditField
        switchingfrequencyendpointHzEditFieldLabel  matlab.ui.control.Label
        switchingfrequencystartpointHzEditField  matlab.ui.control.NumericEditField
        switchingfrequencystartpointHzEditFieldLabel  matlab.ui.control.Label
        phasevoltageendpointVEditField  matlab.ui.control.NumericEditField
        phasevoltageendpointVEditFieldLabel  matlab.ui.control.Label
        phasevoltagestartpointVEditField  matlab.ui.control.NumericEditField
        phasevoltagestartpointVEditFieldLabel  matlab.ui.control.Label
        figureinTab3Switch              matlab.ui.control.Switch
        figureinTab3SwitchLabel         matlab.ui.control.Label
        figureinTab2Switch              matlab.ui.control.Switch
        figureinTab2SwitchLabel         matlab.ui.control.Label
        autosaveSwitch                  matlab.ui.control.Switch
        autosaveSwitchLabel             matlab.ui.control.Label
        diagnoseEditFieldLabel          matlab.ui.control.Label
        diagnoseEditField               matlab.ui.control.EditField
        THDpercentageofoutputcurrentEditFieldLabel  matlab.ui.control.Label
        THDpercentageofoutputcurrentEditField  matlab.ui.control.NumericEditField
        switchingfrequencyHzEditFieldLabel  matlab.ui.control.Label
        switchingfrequencyEditField     matlab.ui.control.NumericEditField
        modulationindexEditField        matlab.ui.control.NumericEditField
        modulationindexEditFieldLabel   matlab.ui.control.Label
        modulationmethodSwitch          matlab.ui.control.Switch
        modulationmethodSwitchLabel     matlab.ui.control.Label
        samplefrequencyHzEditField      matlab.ui.control.NumericEditField
        samplefrequencyHzEditFieldLabel  matlab.ui.control.Label
        stoptimesEditField              matlab.ui.control.NumericEditField
        stoptimesEditFieldLabel         matlab.ui.control.Label
        DClinkvoltageVEditField         matlab.ui.control.NumericEditField
        DClinkvoltageVEditFieldLabel    matlab.ui.control.Label
        phasevoltageVEditField          matlab.ui.control.NumericEditField
        phasevoltageVEditFieldLabel     matlab.ui.control.Label
        frequencyHzEditField            matlab.ui.control.NumericEditField
        frequencyHzEditFieldLabel       matlab.ui.control.Label
        inductorHEditField              matlab.ui.control.NumericEditField
        inductorHEditFieldLabel         matlab.ui.control.Label
        resistorEditField               matlab.ui.control.NumericEditField
        resistorEditFieldLabel          matlab.ui.control.Label
        runButton                       matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        Tab1                            matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        Tab2_3                          matlab.ui.container.Tab
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        Tab3_2                          matlab.ui.container.Tab
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: runButton
        function runButtonPushed(app, event)

app.runButton.Visible='off';

% after the run button is pushed, make the run buttopn invisible.
% double pressed run button can cause programm collapse, therefore hide run
% button when it is pushed. This can also remind the user, programm is
% running

app.modulationindexEditField.FontColor=[1,1,1];
app.THDpercentageofoutputcurrentEditField.FontColor=[1,1,1];

% hiden the numeric value in modulation index edit field and THD-percentage
% edit field. since the numeric edit filed value can only be numeric ,it can 
% not be string for exmaple "waiting for calculation". the solution to solve 
% this probelm can be, changing the front color of the numeric edit field value 
% to white, which is[1,1,1] (Hexadecimal Color Code "#FFFFFF"). It's the same
% color as the background of numeric edit field.

V=app.phasevoltageVEditField.Value;
frequency=app.frequencyHzEditField.Value;
R=app.resistorEditField.Value;
L=app.inductorHEditField.Value;
fs=app.switchingfrequencyEditField.Value;
Vdc=app.DClinkvoltageVEditField.Value;
stoptime=app.stoptimesEditField.Value;
samplefrequency=app.samplefrequencyHzEditField.Value;

% assign the value of the numeric input field to the variable.

 app.diagnoseEditField.Value ='running';
 app.phasevoltageVEditField.Editable= 'off';
 app.frequencyHzEditField.Editable='off';
 app.resistorEditField.Editable='off';
 app.inductorHEditField.Editable='off';
 app.switchingfrequencyEditField.Editable='off';
 app.DClinkvoltageVEditField.Editable='off';
 app.stoptimesEditField.Editable='off';
 app.samplefrequencyHzEditField.Editable='off';
 app.modulationmethodSwitch.Enable='off';
 app.autosaveSwitch.Enable='off';
 app.figureinTab2Switch.Enable='off';
 app.figureinTab3Switch.Enable='off';
 app.phasevoltagestartpointVEditField.Editable='off';
 app.phasevoltageendpointVEditField.Editable='off';
 app.numberofphasevoltagedatepointSpinner.Enable='off';
 app.switchingfrequencystartpointHzEditField.Editable='off';
 app.switchingfrequencyendpointHzEditField.Editable='off';
 app.numberofswitchingfrequencydatepointSpinner.Enable='off';
 app.holdonSwitch.Enable='off';
 
 % assign string "running" to diagnose text edit field. the user is
 % informed the programm is running.

 % change the editablity of edit field and enability switch to off. Imaging  
 % following user scenario. when the programm
 % is running and it already takes the value of the first input from
 % user.Now the user change the input value and give the second input 
 % after push the run botton. it doesn't change the assigned value actually. 
 % But the user think the output corresponds to the second input.
 % Like most of the programm, after pushing the simulation button, 
 % the user interface is locked until the simulation ends, the locked user
 % interface after pushing run button is also implemented in this app.
 % Changing the input value of variable is impossible. 


 if string(app.holdonSwitch.Value) == "off"
 cla(app.UIAxes);
 cla(app.UIAxes_2);
 cla(app.UIAxes_3);
 cla(app.UIAxes_4);
 cla(app.UIAxes_5);
 cla(app.UIAxes_6);
 end

 % if hold on switch is off, use the command cla(app.UIAxes) to clear all 
 % the previous plot.

 if string(app.holdonSwitch.Value) == "on"
     hold(app.UIAxes,'on'); 
     hold(app.UIAxes_2,'on'); 
     hold(app.UIAxes_3,'on'); 
     hold(app.UIAxes_4,'on'); 
     hold(app.UIAxes_5,'on'); 
     hold(app.UIAxes_6,'on'); 
 end

 % if hold on switch is on, hold on the previos plot and continue the new plot.

 app.UIAxes_3.Visible ='off';
 app.UIAxes_4.Visible ='off';
 app.UIAxes_5.Visible ='off';
 app.UIAxes_6.Visible ='off';

 % hide the UIAxes_3(modulation index to THD percentage) 
 % and UIAxes_4 (switching frequency to THD-perncentage)
 % UIAxes_5 (output phase volatge to time)
 % UIAxes_6 (FFT spectrum analysis of the output phase voltage)
 % since it is still unknow, the switch state of the switch "figure in Tab 2" ( 
 % namely UIAxes_5 and UIAxes_6) and switch "figure in Tab 3" (namely UIAxes_3 
 % and UIAxes_4 ). All the plot can be hiden at first. Once it comes to the
 % code to identify the switching state of the two switch "figure in Tab 2"
 % and "figure in Tab 3". the visibility will be changed by an if
 % statement.


if string(app.modulationmethodSwitch.Value) == "SVPWM"


     app.modulationindexEditField.Value=((3/2)*V)/Vdc;
     if app.modulationindexEditField.Value >1
         app.phasevoltageVEditField.Value=(2/3)*Vdc;
         V=app.phasevoltageVEditField.Value;
         app.modulationindexEditField.Value=1;
         app.diagnoseEditField.Value =['phase voltage should be less ' ...
             'than two thirds of DC link voltage.'];
     end

% if the switching state of the switch "modulation method" is "SVPWM", the
% space vector puls width modulation method will be used.
% the modulation index of SVPWM is defined as refernce voltage divide two
% thirds of DC-link voltage and should be equal to or less than one. when
% modulation index is greater than one, phase volate editfield value in
% user interface will be set to two thirds of DC-link voltage, and the
% value of variable v is also assigned with two thirds of DC-link voltage. text
% in diagnose editfield will inform the user that the given phase voltage is out
% of limit. But the simulation still runs with the maximal modulation
% index 1.


if ((samplefrequency<2*fs)||(samplefrequency<2e4))
   app.diagnoseEditField.Value =['sample frequency should be greater than ' ...
       'or equal to two times of switching frequency and at least be 20000Hz.']; 
   
   samplefrequency=2*fs;
   if samplefrequency<2e4
       samplefrequency=2e4;
   end
   app.samplefrequencyHzEditField.Value=samplefrequency;
   
 
end

% an if statement is implemented to make the samplefrequency satisfiy two
% conditions. The first condition is samplefrequency must be greater than
% two times of switching frequency, according to Nyquist–Shannon sampling theorem
% if a function x(t) contains no frequencies higher than B hertz, then it
% can be completely determined from its ordinates at a sequence of points
% spaced less than 1/(2*B) seconds apart. The second condition is
% samplefrequency must be equal to or greater than 2e4, otherweise the accuracy of the
% FFT spectrum analysis can not be guaranteed.

k2=5*(L/R)+(1/frequency);

% define a varibale k2. k2 is the transient state of the circuit plus one
% period of modulating signal. If the stopetime is less than k2, the FFT
% spectrum analysis will take the signal before the circuit stationary is
% and will lead to mistake.

samplesize=stoptime*samplefrequency;
stepsize=1/samplefrequency;
u_sampletime=1/samplefrequency;
% u_sampletime=stoptime/samplesize;

if k2>stoptime
app.diagnoseEditField.Value ='stop time is out of limits.';
stoptime=k2;
app.stoptimesEditField.Value=stoptime;
end  
% stoptime must be grerater than or equal to k2.

% if ((5*(L/R))/stepsize)>9e18
%    app.diagnoseEditField.Value ='samplesize is out of limits.';
% cla(app.UIAxes);
% cla(app.UIAxes_2);
% app.THDpercentageofoutputcurrentEditField.Value=0;
% end
% if ((1/frequency)/stepsize)>9e18
%     app.diagnoseEditField.Value ='samplesize is out of limits.';
% cla(app.UIAxes);
% cla(app.UIAxes_2);
% app.THDpercentageofoutputcurrentEditField.Value=0;
% end
% open_system('SVPWM_self');


load_system('SVPWM_self');
mdlWks = get_param('SVPWM_self','ModelWorkspace');
assignin(mdlWks,'V',V);
assignin(mdlWks,'frequency',frequency);
% assignin(mdlWks,'Vn',Vn);
assignin(mdlWks,'R',R);
assignin(mdlWks,'L',L);
% assignin(mdlWks,'X',X);
assignin(mdlWks,'fs',fs);
% assignin(mdlWks,'P',P);
% assignin(mdlWks,'Q',Q);
assignin(mdlWks,'Vdc',Vdc);
assignin(mdlWks,'stoptime',stoptime);
assignin(mdlWks,'samplefrequency',samplefrequency);
assignin(mdlWks,'samplesize',samplesize);
assignin(mdlWks,'stepsize',stepsize);
assignin(mdlWks,'u_sampletime',u_sampletime);


 [Vanout,Vanout_new,tout_new,Iout_new,tout,Iout,f,P1,P1_V, THD_percentage, simOut]=...
 partfourierexperiment_1 (stepsize,V, ...
 frequency,R,L,fs,Vdc,stoptime,samplefrequency, u_sampletime);

%  [Vanout,Vanout_new,tout_new,Iout_new,Iout,tout,f,P1,P1_V, THD_percentage, simOut]=...
%  partfourierexperiment_1 (stepsize,V, ...
%  frequency,R,L,fs,Vdc,stoptime,samplefrequency, u_sampletime);

% figure('Name','Output currrent and FFT analysis');
% subplot(2,1,1);
% plot(tout,Iout);

plot(app.UIAxes,tout_new,Iout_new,'DisplayName',['frequency' ': ' num2str(frequency)...
  ' Hz'  ', ' 'switching frequency' ': ' num2str(fs) ' Hz'...
  ', '  'modulation index' ': ' num2str(app.modulationindexEditField.Value)]);
xlim(app.UIAxes,[tout_new(1,1),tout_new(numel(tout_new),1)]);
% xticks(app.UIAxes,[linspace(tout_new(1,1),tout_new(numel(tout_new),1),9)]);
% yticks(app.UIAxes,[linspace(min(Iout_new),max(Iout_new),9)]);
xticks(app.UIAxes,'auto');
yticks(app.UIAxes,'auto');
legend(app.UIAxes);
% xlabel("Time (s)");
% ylabel("Current (A)");
% subplot(2,1,2);
stem(app.UIAxes_2,f,P1,'DisplayName',['frequency' ': ' num2str(frequency)...
  ' Hz'  ', ' 'switching frequency' ': ' num2str(fs) ' Hz'...
  ', '  'modulation index' ': ' num2str(app.modulationindexEditField.Value)]);
xlim(app.UIAxes,[tout_new(1,1),tout_new(numel(tout_new),1)]);
% xlabel("Frequency (Hz)");
% ylabel("Current (A)");
% save("P1.mat","P1");

xlim(app.UIAxes_2,[0 fs]);

xticks(app.UIAxes_2,'auto');

% if numel(0:200:fs)<=10
% xticks(app.UIAxes_2,0:50:fs);
% elseif (numel(0:200:fs)>10) && (numel(0:200:fs)<=20)
%     xticks(app.UIAxes_2,0:100:fs);
% elseif (numel(0:200:fs)>20) && (numel(0:200:fs)<=30)
%     xticks(app.UIAxes_2,0:200:fs);
% elseif (numel(0:200:fs)>30) && (numel(0:200:fs)<=40)
%     xticks(app.UIAxes_2,0:400:fs);
% else (numel(0:200:fs)>40)
%     xticks(app.UIAxes_2,0:500:fs);
% end
% yticks(app.UIAxes_2,[linspace(min(P1),max(P1),10)]);
yticks(app.UIAxes_2,'auto');
legend(app.UIAxes_2);
app.THDpercentageofoutputcurrentEditField.Value=THD_percentage;
app.modulationindexEditField.FontColor=[0,0,0];
app.THDpercentageofoutputcurrentEditField.FontColor=[0,0,0];
if string(app.autosaveSwitch.Value) == "on"
    cd 'C:\Users\Bob\OneDrive\桌面\FP EAM\simulink\my-own-project\pwm and svpwm_experiment_4\';

for n10=1:10
if ~exist(['simulation_Data' num2str(n10) '\'])
break
end
end
mkdir (['simulation_Data' num2str(n10) '\']);
cd (['simulation_Data' num2str(n10) '\']);
h(1)=app.UIAxes;
exportgraphics(h(1),'output_phase_current_svpwm.jpg');
h(2)=app.UIAxes_2;
exportgraphics(h(2),'FFT_spectrum_svpwm.jpg');
sampledata=[(tout_new.');(Iout_new.')];
save('sampledata_svpwm.mat','sampledata');
end
THD_percentage1=[];
THD_percentage2=[];
if string(app.figureinTab2Switch.Value) == "show"
    app.UIAxes_5.Visible ='on';
 app.UIAxes_6.Visible ='on';
  plot(app.UIAxes_5,tout_new,Vanout_new,'DisplayName',['frequency' ': ' num2str(frequency)...
  ' Hz'  ', ' 'switching frequency' ': ' num2str(fs) ' Hz'...
  ', '  'modulation index' ': ' num2str(app.modulationindexEditField.Value)]);
xlim(app.UIAxes,[tout_new(1,1),tout_new(numel(tout_new),1)]);
  xlim(app.UIAxes_5,[tout_new(1,1),tout_new(numel(tout_new),1)]);
  xticks(app.UIAxes_5,'auto');
yticks(app.UIAxes_5,'auto');
legend(app.UIAxes_5);
% P1_max=max(P1);
% P1_n=P1./P1_max;
stem(app.UIAxes_6,f,P1_V,'DisplayName',['frequency' ': ' num2str(frequency)...
  ' Hz'  ', ' 'switching frequency' ': ' num2str(fs) ' Hz'...
  ', '  'modulation index' ': ' num2str(app.modulationindexEditField.Value)]);
xlim(app.UIAxes_6,[0 fs]);
xticks(app.UIAxes_6,'auto');
yticks(app.UIAxes_6,'auto');
legend(app.UIAxes_6);
end
if string(app.figureinTab3Switch.Value) == "show"

     app.UIAxes_3.Visible ='on';
 app.UIAxes_4.Visible ='on';
 V_start =app.phasevoltagestartpointVEditField.Value;
 V_end =app.phasevoltageendpointVEditField.Value;
 V_num=app.numberofphasevoltagedatepointSpinner.Value;
 
 if ((V_start>(2*Vdc/3))||(V_end>(2*Vdc/3))||(V_start==0)||(V_end==0))
V_start=1;
V_end=Vdc/3;
app.phasevoltagestartpointVEditField.Value=1;
app.phasevoltageendpointVEditField.Value=Vdc/3;

app.diagnoseEditField.Value =['phase voltage date points should be greater '...
    'than zero and less than two thirds of DC link voltage.'];
 end
if V_start>V_end
  
app.diagnoseEditField.Value =['phase voltage start point value must ' ...
    'be less than or equal to end point.'];
app.phasevoltagestartpointVEditField.Value=1;
app.phasevoltageendpointVEditField.Value=Vdc/3;
V_start=1;
V_end=Vdc/3;
end
if V_start==V_end
    V_num=1;
end
 V_point=linspace(V_start,V_end,V_num); 
 for n6=1:V_num
   

assignin(mdlWks,'V',V_point(n6));

[~,~,~,~,~,~,~,~,~,THD_percentage, ~]=partfourierexperiment_1...
    (stepsize,V_point(n6),frequency,R,L,fs,Vdc, ...
    stoptime,samplefrequency, u_sampletime);
THD_percentage1=[THD_percentage1,THD_percentage];
end
m=V_point./((2/3)*Vdc);
plot(app.UIAxes_3,m,THD_percentage1,'-o','DisplayName',['frequency' ': ' num2str(frequency)...
  ' Hz'  ', ' 'switching frequency' ': ' num2str(fs) ' Hz'...
  ]);
xticks(app.UIAxes_3,'auto');
yticks(app.UIAxes_3,'auto');
legend(app.UIAxes_3);
end
if string(app.figureinTab3Switch.Value) == "show"
    app.UIAxes_3.Visible ='on';
 app.UIAxes_4.Visible ='on';
assignin(mdlWks,'V',V);
fs_start=app.switchingfrequencystartpointHzEditField.Value;
fs_end=app.switchingfrequencyendpointHzEditField.Value;
fs_num=app.numberofswitchingfrequencydatepointSpinner.Value;
%  if ((V_start>=Vdc)||(V_end>Vdc)||(V_start==0)||(V_end==0))
% V_start=1;
% V_end=Vdc;
% app.phasevoltagestartpointVEditField.Value=1;
% app.phasevoltageendpointVEditField.Value=Vdc;
% V_point=linspace(1,Vdc,V_num);
% app.diagnoseEditField.Value ='phase voltage date points are out of limits.';
%  end
% if V_start>=V_end
%     V_point=linspace(1,Vdc,V_num); 
% app.diagnoseEditField.Value ='start point value must be samller than end point.';
% app.phasevoltagestartpointVEditField.Value=1;
% app.phasevoltageendpointVEditField.Value=Vdc;
%  end
if ((fs_start==0)||(fs_end==0))
    fs_start=1;
    fs_end=2e3;
    app.switchingfrequencystartpointHzEditField.Value=1;
    app.switchingfrequencyendpointHzEditField.Value=2e3;
    app.diagnoseEditField.Value =['switching frequency date points should ' ...
        'be greater than zero.'];
    
end   
if fs_start>fs_end
    fs_start=1;
    fs_end=2e3;
    app.switchingfrequencystartpointHzEditField.Value=1;
    app.switchingfrequencyendpointHzEditField.Value=2e3;
    app.diagnoseEditField.Value =['switching frequency start point ' ...
        'value must be less than or equal to end point.'];
    
end
if fs_start==fs_end
    fs_num=1;
end
fs_point=linspace(fs_start,fs_end,fs_num);
for n7=1:fs_num
   
assignin(mdlWks,'fs',fs_point(n7));

[~,~,~,~,~,~,~,~,~,THD_percentage, ~]...
    =partfourierexperiment_1 (stepsize,V,frequency,R,L,fs_point(n7)...
    ,Vdc,stoptime,samplefrequency, u_sampletime);
THD_percentage2=[THD_percentage2,THD_percentage];
end

plot(app.UIAxes_4,fs_point,THD_percentage2,'-o','DisplayName',['frequency' ': ' num2str(frequency)...
  ' Hz'  ', ' ...
  'modulation index' ': ' num2str(app.modulationindexEditField.Value)]);
xticks(app.UIAxes_4,'auto');
yticks(app.UIAxes_4,'auto');
legend(app.UIAxes_4);
end
app.phasevoltageVEditField.Editable= 'on';
 app.frequencyHzEditField.Editable='on';
 app.resistorEditField.Editable='on';
 app.inductorHEditField.Editable='on';
 app.switchingfrequencyEditField.Editable='on';
 app.DClinkvoltageVEditField.Editable='on';
 app.stoptimesEditField.Editable='on';
 app.samplefrequencyHzEditField.Editable='on';
 app.modulationmethodSwitch.Enable='on';
 app.autosaveSwitch.Enable='on';
 app.figureinTab2Switch.Enable='on';
 app.figureinTab3Switch.Enable='on';
 app.phasevoltagestartpointVEditField.Editable='on';
 app.phasevoltageendpointVEditField.Editable='on';
 app.numberofphasevoltagedatepointSpinner.Enable='on';
 app.switchingfrequencystartpointHzEditField.Editable='on';
 app.switchingfrequencyendpointHzEditField.Editable='on';
 app.numberofswitchingfrequencydatepointSpinner.Enable='on';
 app.holdonSwitch.Enable='on';
if app.diagnoseEditField.Value =="running"
    app.diagnoseEditField.Value ='all value are within limits.';
end
app.runButton.Visible='on';
else 
     
%     app.modulationindexEditField.Visible='on';
%      app.modulationindexLabel.Visible='on';
     app.modulationindexEditField.Value=V/Vdc;
     if app.modulationindexEditField.Value>1
         app.phasevoltageVEditField.Value=Vdc;
         V=Vdc;
         app.modulationindexEditField.Value=1;
         app.diagnoseEditField.Value =['phase voltage should be ' ...
             'less than DC link voltage.'];
     end
% if  fs>9e5
%          app.diagnoseEditField.Value =['switching frequency should ' ...
%              'be less than 900000Hz.'];
%          fs=2e3;
%          app.switchingfrequencyEditField.Value=2e3;
% end
% if (samplefrequency>9e5)  
% app.diagnoseEditField.Value ='sample frequency should be less than 900000Hz.';
% samplefrequency=3e5;
% app.samplefrequencyHzEditField.Value=samplefrequency;
% end
if ((samplefrequency<2*fs)||(samplefrequency<2e4))
   app.diagnoseEditField.Value =['switching frequency should be ' ...
       'less than or equal to two times of sample frequency ' ...
       'and at leat be 20000Hz.']; 
   
   samplefrequency=2*fs;
   if samplefrequency<2e4
       samplefrequency=2e4;
   end
   app.samplefrequencyHzEditField.Value=samplefrequency;
   
end
k2=5*(L/R)+(1/frequency);
% fs=5000;
% P=[(Vn)^2]/R;
% Q=[(Vn)^2]/X;
% Vdc=780;
% stoptime=0.43;
% samplefrequency=34567;
samplesize=stoptime*samplefrequency;
stepsize=stoptime/samplesize;
u_sampletime=stepsize;
% open_system('SVPWM_self');
m=V/Vdc;
if k2>stoptime
app.diagnoseEditField.Value ='stop time is out of limits.';
stoptime=k2;
app.stoptimesEditField.Value=stoptime;
end     
load_system('PWM_self_modified_PWM');
mdlWks = get_param('PWM_self_modified_PWM','ModelWorkspace');
assignin(mdlWks,'V',V);
assignin(mdlWks,'Vdc',Vdc);
assignin(mdlWks,'frequency',frequency);
assignin(mdlWks,'m',m);
assignin(mdlWks,'R',R);
assignin(mdlWks,'L',L);
% assignin(mdlWks,'X',X);
assignin(mdlWks,'fs',fs);
% assignin(mdlWks,'P',P);
% assignin(mdlWks,'Q',Q);

assignin(mdlWks,'stoptime',stoptime);
assignin(mdlWks,'samplefrequency',samplefrequency);
assignin(mdlWks,'samplesize',samplesize);
assignin(mdlWks,'stepsize',stepsize);
assignin(mdlWks,'u_sampletime',u_sampletime);

[Vanout,Vanout_new,tout_new,Iout_new,tout,Iout,f,P1,P1_V, THD_percentage, simOut]...
    =partfourierexperiment_pwm_1 (stepsize,Vdc,V,m,frequency,R,L,...
    fs,stoptime,samplefrequency, u_sampletime);

% figure('Name','Output currrent and FFT analysis');
% subplot(2,1,1);
% plot(tout,Iout);
plot(app.UIAxes,tout_new,Iout_new,'DisplayName',['frequency' ': ' num2str(frequency)...
  ' Hz'  ', ' 'switching frequency' ': ' num2str(fs) ' Hz'...
  ', '  'modulation index' ': ' num2str(app.modulationindexEditField.Value)]);
xlim(app.UIAxes,[tout_new(1,1),tout_new(numel(tout_new),1)]);
% M1=[];
% M1=linspace(tout_new(1,1),tout_new(numel(tout_new),1),10);
% xticks(app.UIAxes,[linspace(tout_new(1,1),tout_new(numel(tout_new),1),9)]);
% yticks(app.UIAxes,[linspace(min(Iout_new),max(Iout_new),9)]);
xticks(app.UIAxes,'auto');
yticks(app.UIAxes,'auto');
legend(app.UIAxes);
% xlabel("Time (s)");
% ylabel("Current (A)");
% subplot(2,1,2);
stem(app.UIAxes_2,f,P1,'DisplayName',['frequency' ': ' num2str(frequency)...
  ' Hz'  ', ' 'switching frequency' ': ' num2str(fs) ' Hz'...
  ', '  'modulation index' ': ' num2str(app.modulationindexEditField.Value)]);
% xlabel("Frequency (Hz)");
% ylabel("Current (A)");
% save("P1.mat","P1");
xlim(app.UIAxes_2,[0 fs]);
xticks(app.UIAxes_2,'auto');
% if numel(0:200:fs)<=10
% xticks(app.UIAxes_2,0:50:fs);
% elseif (numel(0:200:fs)>10) && (numel(0:200:fs)<=20)
%     xticks(app.UIAxes_2,0:100:fs);
% elseif (numel(0:200:fs)>20) && (numel(0:200:fs)<=30)
%     xticks(app.UIAxes_2,0:200:fs);
% elseif (numel(0:200:fs)>30) && (numel(0:200:fs)<=40)
%     xticks(app.UIAxes_2,0:400:fs);
% else (numel(0:200:fs)>40)
%     xticks(app.UIAxes_2,0:500:fs);
% end
yticks(app.UIAxes_2,'auto');
legend(app.UIAxes_2);


app.THDpercentageofoutputcurrentEditField.Value=THD_percentage;
app.modulationindexEditField.FontColor=[0,0,0];
app.THDpercentageofoutputcurrentEditField.FontColor=[0,0,0];
if string(app.autosaveSwitch.Value) == "on"
    cd 'C:\Users\Bob\OneDrive\桌面\FP EAM\simulink\my-own-project\pwm and svpwm_experiment_4\';

for n11=1:10
if ~exist(['simulation_Data' num2str(n11) '\'])
break
end
end
mkdir (['simulation_Data' num2str(n11) '\']);
cd (['simulation_Data' num2str(n11) '\']);
h(11)=app.UIAxes;
exportgraphics(h(11),'output_phase_current_pwm.jpg');
h(21)=app.UIAxes_2;
exportgraphics(h(21),'FFT_spectrum_pwm.jpg');
sampledata_1=[(tout_new.');(Iout_new.')];
save('sampledata_pwm.mat','sampledata_1');
end
if string(app.figureinTab2Switch.Value) == "show"
    app.UIAxes_5.Visible ='on';
 app.UIAxes_6.Visible ='on';
  plot(app.UIAxes_5,tout_new,Vanout_new,'DisplayName',['frequency' ': ' num2str(frequency)...
  ' Hz'  ', ' 'switching frequency' ': ' num2str(fs) ' Hz'...
  ', '  'modulation index' ': ' num2str(app.modulationindexEditField.Value)]);
  xlim(app.UIAxes_5,[tout_new(1,1),tout_new(numel(tout_new),1)]);
  xticks(app.UIAxes_5,'auto');
yticks(app.UIAxes_5,'auto');
legend(app.UIAxes_5);

stem(app.UIAxes_6,f,P1_V,'DisplayName',['frequency' ': ' num2str(frequency)...
  ' Hz'  ', ' 'switching frequency' ': ' num2str(fs) ' Hz'...
  ', '  'modulation index' ': ' num2str(app.modulationindexEditField.Value)]);
xlim(app.UIAxes_6,[0 fs]);
xticks(app.UIAxes_6,'auto');
yticks(app.UIAxes_6,'auto');
legend(app.UIAxes_6);
end

THD_percentage3=[];
THD_percentage4=[];
if string(app.figureinTab3Switch.Value) == "show"
     app.UIAxes_3.Visible ='on';
 app.UIAxes_4.Visible ='on';
 V_start2 =app.phasevoltagestartpointVEditField.Value;
 V_end2 =app.phasevoltageendpointVEditField.Value;
 V_num2=app.numberofphasevoltagedatepointSpinner.Value;
 
 if ((V_start2>Vdc)||(V_end2>Vdc)||(V_start2==0)||(V_end2==0))
V_start2=1;
V_end2=2*Vdc/3;
app.phasevoltagestartpointVEditField.Value=1;
app.phasevoltageendpointVEditField.Value=2*Vdc/3;

app.diagnoseEditField.Value =['phase voltage date points should ' ...
    'be greater than zero and less than DC link voltage.'];
 end
if V_start2>V_end2
  
app.diagnoseEditField.Value =['phase voltage start point ' ...
    'value must be less than or equal to end point.'];
app.phasevoltagestartpointVEditField.Value=1;
app.phasevoltageendpointVEditField.Value=2*Vdc/3;
V_start2=1;
V_end2=2*Vdc/3;
end
if V_start2==V_end2
    V_num2=1;
end
 V_point2=linspace(V_start2,V_end2,V_num2); 

for n8=1:V_num2
   

assignin(mdlWks,'V',V_point2(n8));

[~,~,~,~,~,~,~,~,~,THD_percentage, ~]=partfourierexperiment_pwm_1 ...
    (stepsize,Vdc,V_point2(n8),m,frequency,R,L,fs...
    ,stoptime,samplefrequency, u_sampletime);
THD_percentage3=[THD_percentage3,THD_percentage];
end

plot(app.UIAxes_3,V_point2,THD_percentage3,'-o','DisplayName',['frequency' ': ' num2str(frequency)...
  ' Hz'  ', ' 'switching frequency' ': ' num2str(fs) ' Hz'...
  ]);
xticks(app.UIAxes_3,'auto');
yticks(app.UIAxes_3,'auto');
legend(app.UIAxes_3);
end
if string(app.figureinTab3Switch.Value) == "show"
    app.UIAxes_3.Visible ='on';
 app.UIAxes_4.Visible ='on';
assignin(mdlWks,'V',V);
fs_start2=app.switchingfrequencystartpointHzEditField.Value;
fs_end2=app.switchingfrequencyendpointHzEditField.Value;
fs_num2=app.numberofswitchingfrequencydatepointSpinner.Value;
if ((fs_start2==0)||(fs_end2==0))
    fs_start2=1;
    fs_end2=2e3;
    app.switchingfrequencystartpointHzEditField.Value=1;
    app.switchingfrequencyendpointHzEditField.Value=2e3;
    app.diagnoseEditField.Value =['switching frequency date points should ' ...
        'be greater than zero.'];
    
end   
if fs_start2>fs_end2
    fs_start2=1;
    fs_end2=2e3;
    app.switchingfrequencystartpointHzEditField.Value=1;
    app.switchingfrequencyendpointHzEditField.Value=2e3;
    app.diagnoseEditField.Value =['switching frequency start point ' ...
        'value must be less than or equal to end point.'];
    
end
if fs_start2==fs_end2
    fs_num2=1;
end
fs_point2=linspace(fs_start2,fs_end2,fs_num2);

for n9=1:fs_num2
   
assignin(mdlWks,'fs',fs_point2(n9));

[~,~,~,~,~,~,~,~,~,THD_percentage, ~]=partfourierexperiment_pwm_1 ...
    (stepsize,Vdc,V,m,frequency,R,L,fs_point2(n9)...
    ,stoptime,samplefrequency, u_sampletime);
THD_percentage4=[THD_percentage4,THD_percentage];
end

plot(app.UIAxes_4,fs_point2,THD_percentage4,'-o','DisplayName',...
    ['frequency' ': ' num2str(frequency)...
  ' Hz'  ', ' 'modulation index' ': ' ...
  num2str(app.modulationindexEditField.Value)]);
xticks(app.UIAxes_4,'auto');
yticks(app.UIAxes_4,'auto');
legend(app.UIAxes_4);

end
app.phasevoltageVEditField.Editable='on';
 app.frequencyHzEditField.Editable='on';
 app.resistorEditField.Editable='on';
 app.inductorHEditField.Editable='on';
 app.switchingfrequencyEditField.Editable='on';
 app.DClinkvoltageVEditField.Editable='on';
 app.stoptimesEditField.Editable='on';
 app.samplefrequencyHzEditField.Editable='on';
 app.modulationmethodSwitch.Enable='on';
 app.autosaveSwitch.Enable='on';
 app.figureinTab2Switch.Enable='on';
 app.figureinTab3Switch.Enable='on';
 app.phasevoltagestartpointVEditField.Editable='on';
 app.phasevoltageendpointVEditField.Editable='on';
 app.numberofphasevoltagedatepointSpinner.Enable='on';
 app.switchingfrequencystartpointHzEditField.Editable='on';
 app.switchingfrequencyendpointHzEditField.Editable='on';
 app.numberofswitchingfrequencydatepointSpinner.Enable='on';
 app.holdonSwitch.Enable='on';
if app.diagnoseEditField.Value =="running"
    app.diagnoseEditField.Value ='all value are within limits.';
end
app.runButton.Visible='on';
end


% if string(app.autosaveSwitch.Value) == "on"
%     cd 'C:\Users\Bob\OneDrive\桌面\FP EAM\simulink\my-own-project\pwm and svpwm_experiment_4\';
% 
% for n10=1:10
% if ~exist(['simulation_Data' num2str(n10) '\'])
% break
% end
% end
% mkdir (['simulation_Data' num2str(n10) '\']);
% cd (['simulation_Data' num2str(n10) '\']);
% h(1)=app.UIAxes;
% exportgraphics(h(1),'output_phase_current.jpg');
% h(2)=app.UIAxes_2;
% exportgraphics(h(2),'FFT_spectrum.jpg');
% end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Tab 1';
            app.UIAxes_2 = uiaxes(app.Tab1);
            title(app.UIAxes_2, 'FFT spectrum analysis of the output phase current')
            xlabel(app.UIAxes_2, 'frequency (Hz)')
            ylabel(app.UIAxes_2, 'current (A)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes = uiaxes(app.Tab1);
            title(app.UIAxes, 'output phase current')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'current (A)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Tab2_3 = uitab(app.TabGroup);
            app.Tab2_3.Title = 'Tab 2';
            app.UIAxes_5 = uiaxes(app.Tab2_3);
            title(app.UIAxes_5, 'output phase voltage')
            xlabel(app.UIAxes_5, 'time (s)')
            ylabel(app.UIAxes_5, 'voltage (V)')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_6 = uiaxes(app.Tab2_3);
            title(app.UIAxes_6, 'FFT spectrum analysis of the output phase voltage')
            xlabel(app.UIAxes_6, ' frequency (Hz)')
            ylabel(app.UIAxes_6, 'voltage (V)')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.Tab3_2 = uitab(app.TabGroup);
            app.Tab3_2.Title = 'Tab 3';
            app.UIAxes_3 = uiaxes(app.Tab3_2);
            title(app.UIAxes_3, 'modulation index to THD-percentage')
            xlabel(app.UIAxes_3, 'modulation index')
            ylabel(app.UIAxes_3, 'THD-percentage (%)')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.Tab3_2);
            title(app.UIAxes_4, 'switching frequency to THD-percentage')
            xlabel(app.UIAxes_4, 'switching frequency (Hz)')
            ylabel(app.UIAxes_4, 'THD-percentage (%)')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.runButton = uibutton(app.UIFigure, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'run';
            app.resistorEditFieldLabel = uilabel(app.UIFigure);
            app.resistorEditFieldLabel.Text = 'resistor (Ω)';
            app.resistorEditField = uieditfield(app.UIFigure, 'numeric');
            app.resistorEditField.LowerLimitInclusive = 'off';
            app.resistorEditField.Limits = [0 Inf];
            app.resistorEditField.Value = 1;
            app.inductorHEditFieldLabel = uilabel(app.UIFigure);
            app.inductorHEditFieldLabel.Text = 'inductor (H)';
            app.inductorHEditField = uieditfield(app.UIFigure, 'numeric');
            app.inductorHEditField.LowerLimitInclusive = 'off';
            app.inductorHEditField.Limits = [0 Inf];
            app.inductorHEditField.Value = 0.003;
            app.frequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.frequencyHzEditFieldLabel.Text = ' frequency (Hz)';
            app.frequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.frequencyHzEditField.LowerLimitInclusive = 'off';
            app.frequencyHzEditField.Limits = [0 Inf];
            app.frequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.frequencyHzEditField.Value = 50;
            app.phasevoltageVEditFieldLabel = uilabel(app.UIFigure);
            app.phasevoltageVEditFieldLabel.Text = 'phase voltage (V)';
            app.phasevoltageVEditField = uieditfield(app.UIFigure, 'numeric');
            app.phasevoltageVEditField.LowerLimitInclusive = 'off';
            app.phasevoltageVEditField.Limits = [0 Inf];
            app.phasevoltageVEditField.Value = 200;
            app.DClinkvoltageVEditFieldLabel = uilabel(app.UIFigure);
            app.DClinkvoltageVEditFieldLabel.Text = 'DC link voltage (V)';
            app.DClinkvoltageVEditField = uieditfield(app.UIFigure, 'numeric');
            app.DClinkvoltageVEditField.LowerLimitInclusive = 'off';
            app.DClinkvoltageVEditField.Limits = [0 Inf];
            app.DClinkvoltageVEditField.Value = 780;
            app.stoptimesEditFieldLabel = uilabel(app.UIFigure);
            app.stoptimesEditFieldLabel.Text = 'stop time (s)';
            app.stoptimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.stoptimesEditField.LowerLimitInclusive = 'off';
            app.stoptimesEditField.Limits = [0 Inf];
            app.stoptimesEditField.Value = 0.2;
            app.samplefrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.samplefrequencyHzEditFieldLabel.Text = 'sample frequency (Hz)';
            app.samplefrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.samplefrequencyHzEditField.LowerLimitInclusive = 'off';
            app.samplefrequencyHzEditField.UpperLimitInclusive = 'off';
            app.samplefrequencyHzEditField.Limits = [20000 Inf];
            app.samplefrequencyHzEditField.ValueDisplayFormat = '%.0f';
            app.samplefrequencyHzEditField.Value = 300000;
            app.modulationmethodSwitchLabel = uilabel(app.UIFigure);
            app.modulationmethodSwitchLabel.Text = 'modulation method';
            app.modulationmethodSwitch = uiswitch(app.UIFigure, 'slider');
            app.modulationmethodSwitch.Items = {'SPWM', 'SVPWM'};
            app.modulationmethodSwitch.Value = 'SPWM';
            app.modulationindexEditFieldLabel = uilabel(app.UIFigure);
            app.modulationindexEditFieldLabel.Text = 'modulation index';
            app.modulationindexEditField = uieditfield(app.UIFigure, 'numeric');
            app.modulationindexEditField.Editable = 'off';
            app.switchingfrequencyEditField = uieditfield(app.UIFigure, 'numeric');
            app.switchingfrequencyEditField.LowerLimitInclusive = 'off';
            app.switchingfrequencyEditField.Limits = [0 Inf];
            app.switchingfrequencyEditField.ValueDisplayFormat = '%.0f';
            app.switchingfrequencyEditField.Value = 2000;
            app.switchingfrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.switchingfrequencyHzEditFieldLabel.Text = 'switching frequency (Hz)';
            app.THDpercentageofoutputcurrentEditField = uieditfield(app.UIFigure, 'numeric');
            app.THDpercentageofoutputcurrentEditField.Editable = 'off';
            app.THDpercentageofoutputcurrentEditFieldLabel = uilabel(app.UIFigure);
            app.THDpercentageofoutputcurrentEditFieldLabel.Text = 'THD-percentage of output current (%)';
            app.diagnoseEditField = uieditfield(app.UIFigure, 'text');
            app.diagnoseEditField.Editable = 'off';
            app.diagnoseEditFieldLabel = uilabel(app.UIFigure);
            app.diagnoseEditFieldLabel.Text = 'diagnose';
            app.autosaveSwitchLabel = uilabel(app.UIFigure);
            app.autosaveSwitchLabel.Text = 'auto save';
            app.autosaveSwitch = uiswitch(app.UIFigure, 'slider');
            app.autosaveSwitch.Items = {'off', 'on'};
            app.autosaveSwitch.Value = 'off';
            app.figureinTab2SwitchLabel = uilabel(app.UIFigure);
            app.figureinTab2SwitchLabel.Text = 'figure in Tab 2';
            app.figureinTab2Switch = uiswitch(app.UIFigure, 'slider');
            app.figureinTab2Switch.Items = {'hide', 'show'};
            app.figureinTab2Switch.Value = 'hide';
            app.figureinTab3SwitchLabel = uilabel(app.UIFigure);
            app.figureinTab3SwitchLabel.Text = 'figure in Tab 3';
            app.figureinTab3Switch = uiswitch(app.UIFigure, 'slider');
            app.figureinTab3Switch.Items = {'hide', 'show'};
            app.figureinTab3Switch.Value = 'hide';
            app.phasevoltagestartpointVEditFieldLabel = uilabel(app.UIFigure);
            app.phasevoltagestartpointVEditFieldLabel.Text = 'phase voltage start point (V)';
            app.phasevoltagestartpointVEditField = uieditfield(app.UIFigure, 'numeric');
            app.phasevoltagestartpointVEditField.Limits = [0 Inf];
            app.phasevoltagestartpointVEditField.Value = 100;
            app.phasevoltageendpointVEditFieldLabel = uilabel(app.UIFigure);
            app.phasevoltageendpointVEditFieldLabel.Text = 'phase voltage end point (V)';
            app.phasevoltageendpointVEditField = uieditfield(app.UIFigure, 'numeric');
            app.phasevoltageendpointVEditField.Limits = [0 Inf];
            app.phasevoltageendpointVEditField.Value = 400;
            app.switchingfrequencystartpointHzEditFieldLabel = uilabel(app.UIFigure);
            app.switchingfrequencystartpointHzEditFieldLabel.Text = 'switching frequency start point (Hz)';
            app.switchingfrequencystartpointHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.switchingfrequencystartpointHzEditField.Limits = [0 Inf];
            app.switchingfrequencystartpointHzEditField.Value = 1000;
            app.switchingfrequencyendpointHzEditFieldLabel = uilabel(app.UIFigure);
            app.switchingfrequencyendpointHzEditFieldLabel.Text = 'switching frequency end point (Hz)';
            app.switchingfrequencyendpointHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.switchingfrequencyendpointHzEditField.Limits = [0 Inf];
            app.switchingfrequencyendpointHzEditField.Value = 4000;
            app.numberofphasevoltagedatepointSpinnerLabel = uilabel(app.UIFigure);
            app.numberofphasevoltagedatepointSpinnerLabel.Text = {'number of phase voltage'; 'date point'};
            app.numberofphasevoltagedatepointSpinner = uispinner(app.UIFigure);
            app.numberofphasevoltagedatepointSpinner.Limits = [1 15];
            app.numberofphasevoltagedatepointSpinner.Value = 4;
            app.numberofswitchingfrequencydatepointSpinnerLabel = uilabel(app.UIFigure);
            app.numberofswitchingfrequencydatepointSpinnerLabel.Text = {'number of switching frequency'; 'date point'};
            app.numberofswitchingfrequencydatepointSpinner = uispinner(app.UIFigure);
            app.numberofswitchingfrequencydatepointSpinner.Limits = [1 15];
            app.numberofswitchingfrequencydatepointSpinner.Value = 4;
            app.holdonSwitchLabel = uilabel(app.UIFigure);
            app.holdonSwitchLabel.Text = 'hold on';
            app.holdonSwitch = uiswitch(app.UIFigure, 'slider');
            app.holdonSwitch.Items = {'off', 'on'};
            app.holdonSwitch.Value = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app8

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end